classdef methodSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumiiSolveLabel                 matlab.ui.control.Label
        MathproblemIdoitforyouLabel     matlab.ui.control.Label
        ChooseyourtypeofproblemButtonGroup  matlab.ui.container.ButtonGroup
        NolinearequationButton_2        matlab.ui.control.ToggleButton
        NumericalintegrationButton_2    matlab.ui.control.ToggleButton
        NolinearequationsystemButton_2  matlab.ui.control.ToggleButton
        DiferentialequationsystemButton_2  matlab.ui.control.ToggleButton
        ListBox                         matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_4                       matlab.ui.control.ListBox
        UIAxes                          matlab.ui.control.UIAxes
        EcuationEditFieldLabel          matlab.ui.control.Label
        EcuationEditField               matlab.ui.control.EditField
        RangeofIntervalLabel            matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        IterationsLabel                 matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
   app.NolinearequationButton_2
   app.ListBox.Visible= false;
    app.ListBox_2.Visible= true;
     app.ListBox_3.Visible= false;
      app.ListBox_4.Visible= false;
        end

        % Selection changed function: 
        % ChooseyourtypeofproblemButtonGroup
        function ChooseyourtypeofproblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseyourtypeofproblemButtonGroup.SelectedObject;
            if selectedButton==app.NolinearequationButton_2
            app.ListBox_2.Visible=true;
            else
                app.ListBox_2.Visible=false;
               
            end
                if(selectedButton==app.NumericalintegrationButton_2)
            app.ListBox_3.Visible=true;
            else
                app.ListBox_3.Visible=false;
                end
                if(selectedButton==app.NolinearequationsystemButton_2)
            app.ListBox.Visible=true;
            else
                app.ListBox.Visible=false;
                end
                if(selectedButton==app.DiferentialequationsystemButton_2)
            app.ListBox_4.Visible=true;
            else
                app.ListBox_4.Visible=false;
                end
                
            
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumiiSolveLabel = uilabel(app.UIFigure);
            app.NumiiSolveLabel.Text = 'Numii Solve';
            app.MathproblemIdoitforyouLabel = uilabel(app.UIFigure);
            app.MathproblemIdoitforyouLabel.Text = 'Math problem? I do it for you!';
            app.ChooseyourtypeofproblemButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseyourtypeofproblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseyourtypeofproblemButtonGroupSelectionChanged, true);
            app.ChooseyourtypeofproblemButtonGroup.Title = 'Choose your type of problem:';
            app.NolinearequationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationButton_2.Text = 'No linear equation';
            app.NolinearequationButton_2.Value = true;
            app.NumericalintegrationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NumericalintegrationButton_2.Text = 'Numerical integration';
            app.NolinearequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationsystemButton_2.Text = 'No linear equation system';
            app.DiferentialequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.DiferentialequationsystemButton_2.Text = 'Diferential equation system';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Gauss-Seidel', 'Newton-Raphson'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Newton-Raphson';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'Bisection', 'Regular Falsi', 'Simple Iteration fix point', 'Newton- Raphson'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Bisection';
            app.ListBox_3 = uilistbox(app.UIFigure);
            app.ListBox_3.Items = {'Trapezoidal multiple', 'Simpson 3/8 multiple'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Trapezoidal multiple';
            app.ListBox_4 = uilistbox(app.UIFigure);
            app.ListBox_4.Items = {'Euler', 'Clasico Runge-kutta'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = 'Euler';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Error')
            xlabel(app.UIAxes, 'Iterations')
            ylabel(app.UIAxes, 'Ea')
            app.EcuationEditFieldLabel = uilabel(app.UIFigure);
            app.EcuationEditFieldLabel.Text = 'Ecuation';
            app.EcuationEditField = uieditfield(app.UIFigure, 'text');
            app.RangeofIntervalLabel = uilabel(app.UIFigure);
            app.RangeofIntervalLabel.Text = 'Range of Interval';
            app.MinEditField_2Label = uilabel(app.UIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField_2Label = uilabel(app.UIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.IterationsLabel = uilabel(app.UIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = methodSelector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end