classdef methodSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NumiiSolveUIFigure              matlab.ui.Figure
        NumiiSolveLabel                 matlab.ui.control.Label
        MathproblemIdoitforyouLabel     matlab.ui.control.Label
        ChooseyourtypeofproblemButtonGroup  matlab.ui.container.ButtonGroup
        NolinearequationButton_2        matlab.ui.control.ToggleButton
        NumericalintegrationButton_2    matlab.ui.control.ToggleButton
        NolinearequationsystemButton_2  matlab.ui.control.ToggleButton
        DiferentialequationsystemButton_2  matlab.ui.control.ToggleButton
        ListBox                         matlab.ui.control.ListBox
        ListBox_2                       matlab.ui.control.ListBox
        ListBox_3                       matlab.ui.control.ListBox
        ListBox_4                       matlab.ui.control.ListBox
        uiAxesError                     matlab.ui.control.UIAxes
        EcuationEditField               matlab.ui.control.EditField
        RangeofIntervalLabel            matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        IterationsLabel                 matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        EVALUATEButton                  matlab.ui.control.Button
        EcuationxLabel                  matlab.ui.control.Label
        XiSpinnerLabel                  matlab.ui.control.Label
        XiSpinner                       matlab.ui.control.Spinner
        InitialValueEditFieldLabel      matlab.ui.control.Label
        InitialValueEditField           matlab.ui.control.NumericEditField
        uiAxesFunction                  matlab.ui.control.UIAxes
        MsgLabel                        matlab.ui.control.Label
        Label_msg                       matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        BLabel                          matlab.ui.control.Label
        AxiLabel                        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        RootLabel                       matlab.ui.control.Label
        Label_root                      matlab.ui.control.Label
        EaLabel                         matlab.ui.control.Label
        Label_ea                        matlab.ui.control.Label
        UITableRoots                    matlab.ui.control.Table
        NRTable                         matlab.ui.control.Table
        XiSpinner_2Label                matlab.ui.control.Label
        XiSpinner_NR                    matlab.ui.control.Spinner
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        hEditField                      matlab.ui.control.NumericEditField
        SegmentsLabel                   matlab.ui.control.Label
        SegmentsEditField               matlab.ui.control.NumericEditField
        UITableEa                       matlab.ui.control.Table
        XiLabel                         matlab.ui.control.Label
        EALabel                         matlab.ui.control.Label
        xLabel                          matlab.ui.control.Label
        IntegralLabel                   matlab.ui.control.Label
        RIntegralLabel                  matlab.ui.control.Label
        EtLabel                         matlab.ui.control.Label
        Label_et                        matlab.ui.control.Label
        ETLabel                         matlab.ui.control.Label
        PointsLabel                     matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = func(app)
            % Everything disappears and the values are reset
            app.ListBox_4.Visible = false;
            app.ListBox_3.Visible = false;
            app.ListBox_2.Visible = false;
            app.ListBox.Visible = false;
            % Answer
            app.Label_root.Visible = false;
            app.Label_ea.Visible = false;
            app.Label_msg.Visible = false;
            app.RootLabel.Visible = false;
            app.EaLabel.Visible = false;
            app.PointsLabel.Visible = false;
            app.ETLabel.Visible = false;
            app.MsgLabel.Visible = false;
            app.IntegralLabel.Visible = false;
            app.RIntegralLabel.Visible = false;
            % Graph
            app.uiAxesError.BackgroundColor = [0.8118 0.9098 0.9686];
            app.uiAxesFunction.BackgroundColor = [0.8118 0.9098 0.9686];
            app.uiAxesError.Visible = false;
            app.uiAxesFunction.Visible = false;
            cla(app.uiAxesError);            
            cla(app.uiAxesFunction);
            title(app.uiAxesFunction, 'Function');
            legend(app.uiAxesFunction,'off');
            legend(app.uiAxesError,'off');
            % Tables
            app.UITable.Visible = false;
            app.UITable.Enable = 'on';
            app.UITable.Data = zeros(2);
            app.UITable2.Visible = false;
            app.UITable2.Enable = 'on';
            app.UITable2.Data = zeros(2,1);
            app.UITableRoots.Visible = false;
            app.UITableEa.Visible = false;
            app.NRTable.Visible = false;
            app.AxiLabel.Visible = false;
            app.BLabel.Visible = false;
            app.EALabel.Visible = false;
            app.EtLabel.Visible = false;
            app.Label_et.Visible = false;
            app.XiLabel.Visible = false;
            app.UITable.ColumnEditable = true;
            app.UITable2.ColumnEditable = true;
            app.NRTable.Data = cellstr(['a1*x1^b1+a2*x2^b2+...+an*xn^bn';'a1*x1^b1+a2*x2^b2+...+an*xn^bn']);
            %NR spinner
            app.XiSpinner_NR.Visible = false;
            app.XiSpinner_NR.Value = 2;           
            app.XiSpinner_2Label.Visible = false;
            % x#
            app.XiSpinner.Visible = false;
            app.XiSpinner.Value = 2;
            app.XiSpinnerLabel.Visible = false;
            % Initail value
            app.InitialValueEditFieldLabel.Visible = false;
            app.InitialValueEditField.Visible = false;
            app.InitialValueEditField.Value = 0;
            % Ecuation
            app.EcuationEditField.Visible = false;
            app.EcuationxLabel.Visible = false;
            app.xLabel.Visible = false;
            app.EcuationEditField.Value = "";
            % Range
            app.MaxEditField_2.Visible = false;
            app.MaxEditField_2Label.Visible = false;
            app.MaxEditField_2.Value = 0;
            app.MinEditField_2Label.Visible = false;
            app.MinEditField_2.Visible = false;
            app.MinEditField_2.Value = 0;
            app.RangeofIntervalLabel.Visible = false;
            % Iterations
            app.IterationsLabel.Visible = false;
            app.IterationsEditField.Visible = false;
            app.IterationsEditField.Value = 1;
            %initial variables
            app.xEditField.Visible = false;
            app.xEditField_2Label.Visible = false;
            app.yEditField.Visible = false;
            app.hEditField.Visible = false;
            app.hEditFieldLabel.Visible = false;
            app.yEditFieldLabel.Visible = false;
            app.SegmentsEditField.Visible = false;
            app.SegmentsLabel.Visible = false;
            
        end
        
        %graphicate a function 
        function results = graphicate(app,min,max,h)
            %delete everthing from graph
            cla(app.uiAxesFunction);
            
            % Set the title
            title(app.uiAxesFunction, h);
            xlabel(app.uiAxesFunction, min + " \leq x \leq " + max);
            
            %get the function
            handle = matlabFunction(evalin(symengine, h));
            
            % Calculing the limits
            position = (max-min)/100;
            x = min:position:max;
            y = handle(x);
            
            %graphicate
            plot(app.uiAxesFunction,x,y,'Linewidth',2);
        end          
    
        %graphicate 2 functions, the original and the generated with the method
        function graphicate2func(app,min,max,h,v)
            handle = matlabFunction(evalin(symengine, h));
            %delete everthing from graph
            cla(app.uiAxesFunction);
            
            %Set the title, xLabel
            title(app.uiAxesFunction, h);
            xlabel(app.uiAxesFunction, min + " \leq x \leq " + max);
            
            
            % CalculAte the limits
            position = (max-min)/100;
            x = min:position:max;
            y = handle(x);
            
            %Graph the functions
            plot(app.uiAxesFunction,v(1,:),v(2,:), '--bo', 'Linewidth',1,'MarkerFaceColor','b');
            hold(app.uiAxesFunction, 'on');
            plot(app.uiAxesFunction,x,y,'-r','linewidth',2,'MarkerFaceColor','r');     
            hold(app.uiAxesFunction, 'off')
        end
        
        %graphicate 2 functions, the original and the generated with the Runge Kutta order 4
        function graphRungeKutta(app,h,v)
            %delete everthing from graph
            cla(app.uiAxesFunction);
            
            %Set the title
            title(app.uiAxesFunction, h);
            handle = matlabFunction(evalin(symengine, h));
            
            % Calculate x,y
            minX = min(v(1,:));
            maxX = max(v(1,:));
                            
            position = (maxX-minX)/100;
            x = minX:position:maxX;
            
            minY = min(v(2,:));
            maxY = max(v(2,:));
                                        
            position = (maxY-minY)/100;
            y = minY:position:maxY;
            
            g = handle(x,y);
            
            %Graph the functions
            plot(app.uiAxesFunction,v(1,:),v(2,:), '--bo', 'Linewidth',1,'MarkerFaceColor','b');
            hold(app.uiAxesFunction, 'on');
            plot(app.uiAxesFunction,x,g,'-r','linewidth',2,'MarkerFaceColor','r');     
            hold(app.uiAxesFunction, 'off');
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func(app);
            % by default is selected Nolinearequation and Biseccion
            app.ListBox_2.Value = "Bisection";
            app.ListBox_2.Visible = true;
            
            % Ecuation
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            
            % Range
            app.MaxEditField_2.Visible = true;
            app.MaxEditField_2Label.Visible = true;
            app.MinEditField_2Label.Visible = true;
            app.MinEditField_2.Visible = true;
            app.RangeofIntervalLabel.Visible = true;
            
            % Iterations
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            
            % Graph
            app.uiAxesError.Visible = true;
            app.uiAxesFunction.Visible = true;
        end

        % Selection changed function: 
        % ChooseyourtypeofproblemButtonGroup
        function ChooseyourtypeofproblemButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseyourtypeofproblemButtonGroup.SelectedObject;
            % Everything disappears
            func(app);
            % Only what is selected appears
            switch(selectedButton)
                case app.NolinearequationButton_2
                    % by default is selected Biseccion
                    app.ListBox_2.Visible=true;
                    app.ListBox_2.Value = "Bisection";
                    
                    % objects that it needs
                    
                    % Ecuation
                    app.EcuationEditField.Visible = true;
                    app.EcuationxLabel.Visible = true;
                    
                    % Range
                    app.MaxEditField_2.Visible = true;
                    app.MaxEditField_2Label.Visible = true;
                    app.MinEditField_2Label.Visible = true;
                    app.MinEditField_2.Visible = true;
                    app.RangeofIntervalLabel.Visible = true;
                    
                    % Iterations
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    
                    % Graph
                    app.uiAxesError.Visible = true;
                    app.uiAxesFunction.Visible = true;
                case app.NumericalintegrationButton_2
                    app.ListBox_3.Visible=true;
                    % by default is selected Trapezoidal multiple
                    app.ListBox_3.Value = "Trapezoidal multiple";
                    
                    % Ecuation
                    app.EcuationEditField.Visible = true;
                    app.EcuationxLabel.Visible = true;
                    
                    % Range
                    app.MaxEditField_2.Visible = true;
                    app.MaxEditField_2Label.Visible = true;
                    app.MinEditField_2Label.Visible = true;
                    app.MinEditField_2.Visible = true;
                    app.RangeofIntervalLabel.Visible = true;
                    
                    % Segments 
                    app.SegmentsEditField.Visible = true;
                    app.SegmentsLabel.Visible = true;
                    
                    % Graph
                    app.uiAxesFunction.Visible = true;
                    
                case app.NolinearequationsystemButton_2
                    app.ListBox.Visible=true;
                    % by default is selected Gauss-Seidel
                    app.ListBox.Value = "Gauss-Seidel";
                    % objects that it needs
                    
                    % Tables
                    app.UITable.Visible = true;
                    app.UITable2.Visible = true;
                    app.AxiLabel.Visible = true;
                    app.BLabel.Visible = true;
                    
                    % Iterations
                    app.IterationsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    
                    % xi
                    app.XiSpinner.Visible = true;
                    app.XiSpinnerLabel.Visible = true;
                    
                    % Graph Ea
                    app.uiAxesError.Visible = true;
                    
                case app.DiferentialequationsystemButton_2
                    app.ListBox_4.Visible=true;
                    % by default is selected Euler
                    app.ListBox_4.Value = "Euler";
                    
                    % Ecuation
                    app.EcuationEditField.Visible = true;
                    app.EcuationxLabel.Visible = true;
                    
                    %initial variables (x,y,h)
                    app.xEditField.Visible = true;
                    app.yEditField.Visible = true;
                    app.hEditField.Visible = true;
                    app.yEditFieldLabel.Visible = true;
                    app.hEditFieldLabel.Visible = true;
                    app.xEditField_2Label.Visible = true;
                    
                    %Itereations
                    app.PointsLabel.Visible = true;
                    app.IterationsEditField.Visible = true;
                    
                    %Function Graph
                    app.uiAxesFunction.Visible = true;
            end
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            value = app.ListBox_4.Value;
            % Everything disappears
            func(app);
            % objects that all functions need
            app.ListBox_4.Visible = true;
            
            % Ecuation
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            
            %initial variables
            app.xEditField.Visible = true;
            app.yEditField.Visible = true;
            app.hEditField.Visible = true;
            app.yEditFieldLabel.Visible = true;
            app.hEditFieldLabel.Visible = true;
            app.xEditField_2Label.Visible = true;
            
            %Itereations
            app.PointsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            
            %Graphics
            app.uiAxesFunction.Visible = true;
            % objects of each function
            if(value == "Euler")
                % missing code
            elseif(value == "Clasico Runge-kutta")
                % missing code
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
            % Everything disappears
            func(app);
            
            % objects that all functions need
            app.ListBox.Visible = true;
            
            % Tables
            app.UITable.Visible = true;
            app.UITable2.Visible = true;
            app.AxiLabel.Visible = true;
            app.BLabel.Visible = true;
            
            % Iterations
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            
            % xi
            app.XiSpinner.Visible = true;
            app.XiSpinnerLabel.Visible = true;
            
            % objects of each function
            if(value == "Gauss-Seidel")
                %
            elseif(value == "Newton-Raphson")
                % hide guass-seidel components
                app.XiSpinner.Visible = false;
                app.XiSpinnerLabel.Visible = false;
                app.UITable.Visible = false;
            
                %appear Newton Rhapson components
                app.XiSpinner_NR.Visible = true;
                app.XiSpinner_2Label.Visible = true;
                app.NRTable.Visible = true;
                app.uiAxesError.Visible = true;
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            
            % Everything disappears
            func(app);
            
            % objects that all functions need
            app.ListBox_2.Visible = true;
            
            % Ecuation
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            
            % Iterations
            app.IterationsLabel.Visible = true;
            app.IterationsEditField.Visible = true;
            
            % Graphs
            app.uiAxesError.Visible = true;
            app.uiAxesFunction.Visible = true;
            
            % objects of each function
            if(value == "Bisection" || value == "Regular Falsi")
                % Ranges
                app.MaxEditField_2.Visible = true;
                app.MaxEditField_2Label.Visible = true;
                app.MinEditField_2Label.Visible = true;
                app.MinEditField_2.Visible = true;
                app.RangeofIntervalLabel.Visible = true;
            elseif(value == "Simple Iteration fix point")
                % Initial value
                app.InitialValueEditFieldLabel.Visible = true;
                app.InitialValueEditField.Visible = true;
                app.EcuationxLabel.Visible = false;
                app.xLabel.Visible = true;
            elseif(value =="Newton- Raphson")
                % Initial value
                app.InitialValueEditFieldLabel.Visible = true;
                app.InitialValueEditField.Visible = true;
            end
        end

        % Value changed function: ListBox_3
        function ListBox_3ValueChanged(app, event)
            value = app.ListBox_3.Value;
            % Everything disappears
            func(app);
            % objects that all functions need
            app.ListBox_3.Visible = true;
            
            % Ecuation
            app.EcuationEditField.Visible = true;
            app.EcuationxLabel.Visible = true;
            
            % Range
            app.MaxEditField_2.Visible = true;
            app.MaxEditField_2Label.Visible = true;
            app.MinEditField_2Label.Visible = true;
            app.MinEditField_2.Visible = true;
            app.RangeofIntervalLabel.Visible = true;
            
            % Segments 
            app.SegmentsEditField.Visible = true;
            app.SegmentsLabel.Visible = true;
            
            % Graph
            app.uiAxesFunction.Visible = true;
            % objects of each function
            if(value == "Trapezoidal multiple")
                % missing code
            elseif(value == "Simpson 3/8 multiple")
                % missing code
            end
        end

        % Button pushed function: EVALUATEButton
        function EVALUATEButtonPushed(app, event)
           % It is assumed that the values entered are valid
            switch(app.ChooseyourtypeofproblemButtonGroup.SelectedObject)
                case app.NolinearequationButton_2
                    switch(app.ListBox_2.Value)
                        case "Bisection"
                            % Get the input values
                            handle = app.EcuationEditField.Value;
                            maxVal = app.MaxEditField_2.Value;
                            minVal = app.MinEditField_2.Value;
                            ite = app.IterationsEditField.Value;
                            % Call the function
                            [root, ea, Ea, msg] = bisection(handle,minVal,maxVal,ite);
                            EaSize = size(Ea); 
                            % Graph the Ea
                            xi = 1; 
                            xn = EaSize(1,2);
                            yi = min(Ea); 
                            yn = max(Ea);
                            plot(app.uiAxesError,Ea,'-o','Linewidth',2);
                            if(xn <= 1)
                                xn = inf;
                                yi = 0;
                                yn = inf;
                            end
                            app.uiAxesError.XLim = [xi xn];                           
                            app.uiAxesError.YLim = [yi yn];                           
                            % Graph the function
                            graphicate(app,minVal,maxVal,handle);
                            % Appear the Ea, Root, Msg 
                            app.EaLabel.Visible = true;
                            app.RootLabel.Visible = true;
                            app.MsgLabel.Visible = true;
                            app.Label_root.Visible = true;
                            app.Label_ea.Visible = true;
                            app.Label_msg.Visible = true;
                            % Show the data
                            app.Label_root.Text = num2str(root,'%4.5f');
                            app.Label_ea.Text = num2str(ea,'%4.5f');
                            app.Label_msg.Text = msg;
                        case "Regular Falsi"                            
                            % Get the input values
                            handle = app.EcuationEditField.Value;
                            maxVal = app.MaxEditField_2.Value;
                            minVal = app.MinEditField_2.Value;
                            ite = app.IterationsEditField.Value;
                            % Call the function
                            [root, ea, Ea, msg] = regularFalsi(handle,minVal,maxVal,ite);
                            EaSize = size(Ea); 
                            % Graph the Ea
                            xi = 1; 
                            xn = EaSize(1,2);
                            yi = min(Ea); 
                            yn = max(Ea);
                            plot(app.uiAxesError,Ea,'-o','Linewidth',2);
                            if(xn <= 1)
                                xn = inf;
                                yi = 0;
                                yn = inf;
                            end
                            app.uiAxesError.XLim = [xi xn];                           
                            app.uiAxesError.YLim = [yi yn];                           
                            % Graph the function
                            graphicate(app,minVal,maxVal,handle);
                            % Appear the Ea, Root, Msg 
                            app.EaLabel.Visible = true;
                            app.RootLabel.Visible = true;
                            app.MsgLabel.Visible = true;
                            app.Label_root.Visible = true;
                            app.Label_ea.Visible = true;
                            app.Label_msg.Visible = true;
                            % Show the data
                            app.Label_root.Text = num2str(root,'%4.5f');
                            app.Label_ea.Text = num2str(ea,'%4.5f');
                            app.Label_msg.Text = msg;
                        case "Simple Iteration fix point"
                            % Get the input values
                            handle = app.EcuationEditField.Value;
                            initVal = app.InitialValueEditField.Value;
                            ite = app.IterationsEditField.Value;
                            % Call the function
                            [root, ea, Ea, msg] = fixPoint(handle,initVal,ite);
                            EaSize = size(Ea); 
                            % Graph the Ea
                            xi = 1; 
                            xn = EaSize(1,2);
                            yi = min(Ea); 
                            yn = max(Ea);
                            plot(app.uiAxesError,Ea,'-o','Linewidth',2);
                            if(xn <= 1)
                                xn = inf;
                                yi = 0;
                                yn = inf;
                            end
                            app.uiAxesError.XLim = [xi xn];                           
                            app.uiAxesError.YLim = [yi yn];                           
                            % Graph the function
                            handle = handle + "-x";
                            graphicate(app,initVal,root*2,handle);
                            % Appear the Ea, Root, Msg 
                            app.EaLabel.Visible = true;
                            app.RootLabel.Visible = true;
                            app.MsgLabel.Visible = true;
                            app.Label_root.Visible = true;
                            app.Label_ea.Visible = true;
                            app.Label_msg.Visible = true;
                            % Show the data
                            app.Label_root.Text = num2str(root,'%4.5f');
                            app.Label_ea.Text = num2str(ea,'%4.5f');
                            app.Label_msg.Text = msg;
                        case "Newton- Raphson"
                            % Get the input values
                            handle = app.EcuationEditField.Value;
                            initVal = app.InitialValueEditField.Value;
                            it = app.IterationsEditField.Value;
                            % Call the function
                            [root, ea, Ea, msg] = newtonRaphson(handle,initVal,it);
                            EaSize = size(Ea); 
                            % Graph the Ea
                            xi = 1; 
                            xn = EaSize(1,2);
                            yi = min(Ea); 
                            yn = max(Ea);
                            plot(app.uiAxesError,Ea,'-o','Linewidth',2);
                            if(xn <= 1)
                                xn = inf;
                                yi = 0;
                                yn = inf;
                            end
                            app.uiAxesError.XLim = [xi xn];                           
                            app.uiAxesError.YLim = [yi yn]; 
                            
                            minVal = initVal;
                            maxVal = round(root*2);
                            % Graph the function
                            graphicate(app,minVal,maxVal,handle);
                            % Appear the Ea, Root, Msg 
                            app.EaLabel.Visible = true;
                            app.RootLabel.Visible = true;
                            app.MsgLabel.Visible = true;
                            app.Label_root.Visible = true;
                            app.Label_ea.Visible = true;
                            app.Label_msg.Visible = true;
                            % Show the data
                            app.Label_root.Text = num2str(root,'%4.5f');
                            app.Label_ea.Text = num2str(ea,'%4.5f');
                            app.Label_msg.Text = msg;
                    end
                case app.NumericalintegrationButton_2
                    switch(app.ListBox_3.Value)
                        case "Trapezoidal multiple"
                            % Get the input values
                            handle = app.EcuationEditField.Value;
                            maxVal = app.MaxEditField_2.Value;
                            minVal = app.MinEditField_2.Value;
                            s = app.SegmentsEditField.Value;
                            % Call the function
                            [root,et, v, msg] = trapezoidal(handle,minVal,maxVal,s);
                            mn = min(v(1,:));
                            mx = max(v(1,:));
                            graphicate2func(app,mn,mx,handle,v);
                            % Appear the Root, Msg 
                            app.IntegralLabel.Visible = true;
                            app.RIntegralLabel.Visible = true;
                            app.EtLabel.Visible = true;
                            app.Label_et.Visible = true;
                            % Show the data
                            app.Label_et.Text = num2str(et,'%4.5f');
                            app.RIntegralLabel.Text = num2str(root,'%4.5f');
                            
                        case "Simpson 3/8 multiple"
                            % Get the input values
                            handle = app.EcuationEditField.Value;
                            maxVal = app.MaxEditField_2.Value;
                            minVal = app.MinEditField_2.Value;
                            s = app.SegmentsEditField.Value;
                            % Call the function
                            [root, et, v, msg] = simpson(handle,minVal,maxVal,s);
                            mn = min(v(1,:));
                            mx = max(v(1,:));
                            graphicate2func(app,mn,mx,handle,v);
                            % Appear the Root, Et, Msg 
                            app.IntegralLabel.Visible = true;
                            app.RIntegralLabel.Visible = true;
                            app.Label_msg.Visible = true;
                            app.EtLabel.Visible = true;
                            app.Label_et.Visible = true;
                            % Show the data
                            app.Label_et.Text = num2str(et,'%4.5f');
                            app.RIntegralLabel.Text = num2str(root,'%4.5f');
                    end
                case app.NolinearequationsystemButton_2
                    switch(app.ListBox.Value)
                        case "Gauss-Seidel"
                             % Get the input values
                            vecX = app.UITable.Data;
                            vecB = app.UITable2.Data;
                            ite = app.IterationsEditField.Value;
                            
                            % Call the Method function
                            [root, ea, Ea, msg] = gaussSeidel(vecX,vecB,ite);
                            EaSize = size(Ea); 
                            
                            % Graph the Ea
                            xi = 1; 
                            xn = EaSize(1,1);
                            yi = min(min(Ea,[],2)); 
                            yn = max(max(Ea,[],2));
                            plot(app.uiAxesError,Ea,'-o','Linewidth',2);
                             if(xn <= 1)
                                xn = inf;
                                yi = 0;
                                yn = inf;
                             end
                             display(yn);
                             display(yi);
                            app.uiAxesError.XLim = [xi xn];    
                            app.uiAxesError.YLim = [yi yn];
                            
                            %add Legends to the plot
                            for i=1:length(root)
                                v(i) = "x"+i;
                            end
                            legend(app.uiAxesError,v);
                            
                            % Show the tables data
                            app.XiLabel.Visible = true;
                            app.EALabel.Visible = true;
                            app.UITableEa.Visible = true;
                            app.UITableRoots.Visible = true;
                            app.UITableEa.Data = ea;
                            app.UITableRoots.Data = root;
                            
                        case "Newton-Raphson"
                            matEq = app.NRTable.Data;
                            vVal = app.UITable2.Data;
                            it = app.IterationsEditField.Value;
                            [vRoot, vEa, msg] = NRS(matEq, vVal, it);
                            Ea = double(vEa);
                            % Graph the Ea
                            EaSize = size(Ea); 
                            xi = 1; 
                            xn = EaSize(1,2);
                            yi = min(min(Ea,[],2)); 
                            yn = max(max(Ea,[],2));
                            plot(app.uiAxesError,transpose(Ea),'-o','Linewidth',2);  
                             if(xn <= 1)
                                xn = inf;
                                yi = 0;
                                yn = inf;
                             end
                             display(yn);
                             display(yi);
                            app.uiAxesError.XLim = [xi xn];    
                            app.uiAxesError.YLim = [yi yn];
                            app.Label_msg.Text = msg;
                            app.XiLabel.Visible = true;
                            app.EALabel.Visible = true;
                            app.UITableEa.Visible = true;
                            app.UITableRoots.Visible = true;
                            ea = double(vEa(:,it));
                            app.UITableRoots.Data = vRoot;
                            app.UITableEa.Data = ea;
                            
                            %add Legends to the plot
                            for i=1:length(vRoot)
                                v(i) = "x"+i;
                            end
                            legend(app.uiAxesError,v);
                    end
                case app.DiferentialequationsystemButton_2
                    switch(app.ListBox_4.Value)
                        case "Euler"
                            % Ecuation
                            handle = app.EcuationEditField.Value;
                            %initial variables
                            x = app.xEditField.Value;
                            y = app.yEditField.Value;
                            h = app.hEditField.Value;
                                                        
                            %Itereations
                            it = app.IterationsEditField.Value;
                            
                            [root,v,ea,msg] = Euler(handle,x,y,h,it);
                            mn = min(v(1,:));
                            mx = max(v(1,:));
                            
                            graphicate2func(app,mn,mx,handle,v);
                            
                             % Appear the Root
                            app.XiLabel.Visible = true;
                            app.ETLabel.Visible = true;
                            app.UITableEa.Visible = true;
                            app.UITableRoots.Visible = true;
                            
                            % Show the data
                            app.UITableRoots.Data = v(2,:)';
                            app.UITableEa.Data = ea';
                        case "Clasico Runge-kutta"
                            % Ecuation
                            handle = app.EcuationEditField.Value;
                            %initial variables
                            x = app.xEditField.Value;
                            y = app.yEditField.Value;
                            h = app.hEditField.Value;
                                                        
                            %Itereations
                            it = app.IterationsEditField.Value;
                            
                            [root,v,ea,msg] = RungeKutta(handle,x,y,h,it);
                            graphRungeKutta(app,handle,v);
                            display(v);
                            % Appear the Root, Msg 
                            app.XiLabel.Visible = true;
                            app.ETLabel.Visible = true;
                            app.UITableEa.Visible = true;
                            app.UITableRoots.Visible = true;
                            
                            % Show the data
                            app.UITableRoots.Data = v(2,:)';
                            app.UITableEa.Data = ea';
                    end
            end
        end

        % Value changed function: XiSpinner
        function XiSpinnerValueChanged(app, event)
            value = app.XiSpinner.Value;
            % each time the value is changed, the tables are updated
            app.UITable.Data = zeros(value);
            app.UITable2.Data = zeros(value,1);
        end

        % Value changed function: XiSpinner_NR
        function XiSpinner_NRValueChanged(app, event)
            value = app.XiSpinner_NR.Value;
            f = ['a1*x1^b1+a2*x2^b2+...+an*xn^bn'];
            for i=2:value
                % each time the value is changed, the tables are updated
                f = [ f;'a1*x1^b1+a2*x2^b2+...+an*xn^bn'];
            end
            app.NRTable.Data = cellstr(f);
            app.UITable2.Data = zeros(value,1);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NumiiSolveUIFigure = uifigure;
            app.NumiiSolveUIFigure.Name = 'Numii Solve';
            app.NumiiSolveLabel = uilabel(app.NumiiSolveUIFigure);
            app.NumiiSolveLabel.Text = 'Numii Solve';
            app.MathproblemIdoitforyouLabel = uilabel(app.NumiiSolveUIFigure);
            app.MathproblemIdoitforyouLabel.Text = 'Math problem? I do it for you!';
            app.ChooseyourtypeofproblemButtonGroup = uibuttongroup(app.NumiiSolveUIFigure);
            app.ChooseyourtypeofproblemButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseyourtypeofproblemButtonGroupSelectionChanged, true);
            app.ChooseyourtypeofproblemButtonGroup.Title = 'Choose your type of problem:';
            app.NolinearequationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationButton_2.Text = 'No linear equation';
            app.NolinearequationButton_2.Value = true;
            app.NumericalintegrationButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NumericalintegrationButton_2.Text = 'Numerical integration';
            app.NolinearequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.NolinearequationsystemButton_2.Text = 'No linear equation system';
            app.DiferentialequationsystemButton_2 = uitogglebutton(app.ChooseyourtypeofproblemButtonGroup);
            app.DiferentialequationsystemButton_2.Text = 'Diferential equation system';
            app.ListBox = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox.Items = {'Gauss-Seidel', 'Newton-Raphson'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Newton-Raphson';
            app.ListBox_2 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_2.Items = {'Bisection', 'Regular Falsi', 'Simple Iteration fix point', 'Newton- Raphson'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = 'Bisection';
            app.ListBox_3 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_3.Items = {'Trapezoidal multiple', 'Simpson 3/8 multiple'};
            app.ListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBox_3ValueChanged, true);
            app.ListBox_3.Value = 'Trapezoidal multiple';
            app.ListBox_4 = uilistbox(app.NumiiSolveUIFigure);
            app.ListBox_4.Items = {'Euler', 'Clasico Runge-kutta'};
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            app.ListBox_4.Value = 'Euler';
            app.uiAxesError = uiaxes(app.NumiiSolveUIFigure);
            title(app.uiAxesError, 'Error')
            xlabel(app.uiAxesError, 'Iterations')
            ylabel(app.uiAxesError, 'Ea')
            app.EcuationEditField = uieditfield(app.NumiiSolveUIFigure, 'text');
            app.RangeofIntervalLabel = uilabel(app.NumiiSolveUIFigure);
            app.RangeofIntervalLabel.Text = 'Range of Interval';
            app.MinEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.MaxEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.IterationsLabel = uilabel(app.NumiiSolveUIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.Value = 1;
            app.EVALUATEButton = uibutton(app.NumiiSolveUIFigure, 'push');
            app.EVALUATEButton.ButtonPushedFcn = createCallbackFcn(app, @EVALUATEButtonPushed, true);
            app.EVALUATEButton.Text = 'EVALUATE';
            app.EcuationxLabel = uilabel(app.NumiiSolveUIFigure);
            app.EcuationxLabel.Text = 'Ecuation @(x)';
            app.XiSpinnerLabel = uilabel(app.NumiiSolveUIFigure);
            app.XiSpinnerLabel.Text = 'Xi';
            app.XiSpinner = uispinner(app.NumiiSolveUIFigure);
            app.XiSpinner.Limits = [2 Inf];
            app.XiSpinner.RoundFractionalValues = 'on';
            app.XiSpinner.ValueDisplayFormat = '%11g';
            app.XiSpinner.ValueChangedFcn = createCallbackFcn(app, @XiSpinnerValueChanged, true);
            app.XiSpinner.Value = 2;
            app.InitialValueEditFieldLabel = uilabel(app.NumiiSolveUIFigure);
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.InitialValueEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.uiAxesFunction = uiaxes(app.NumiiSolveUIFigure);
            title(app.uiAxesFunction, 'Function')
            xlabel(app.uiAxesFunction, 'x')
            ylabel(app.uiAxesFunction, 'f(x)')
            app.MsgLabel = uilabel(app.NumiiSolveUIFigure);
            app.MsgLabel.Text = 'Msg:';
            app.Label_msg = uilabel(app.NumiiSolveUIFigure);
            app.Label_msg.Text = '';
            app.UITable2 = uitable(app.NumiiSolveUIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.BLabel = uilabel(app.NumiiSolveUIFigure);
            app.BLabel.Text = 'B';
            app.AxiLabel = uilabel(app.NumiiSolveUIFigure);
            app.AxiLabel.Text = 'Axi';
            app.UITable = uitable(app.NumiiSolveUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.RootLabel = uilabel(app.NumiiSolveUIFigure);
            app.RootLabel.Text = 'Root:';
            app.Label_root = uilabel(app.NumiiSolveUIFigure);
            app.Label_root.Text = '';
            app.EaLabel = uilabel(app.NumiiSolveUIFigure);
            app.EaLabel.Text = 'Ea:';
            app.Label_ea = uilabel(app.NumiiSolveUIFigure);
            app.Label_ea.Text = '';
            app.UITableRoots = uitable(app.NumiiSolveUIFigure);
            app.UITableRoots.ColumnName = '';
            app.UITableRoots.RowName = {};
            app.NRTable = uitable(app.NumiiSolveUIFigure);
            app.NRTable.ColumnName = '';
            app.NRTable.RowName = {};
            app.NRTable.ColumnEditable = true;
            app.XiSpinner_2Label = uilabel(app.NumiiSolveUIFigure);
            app.XiSpinner_2Label.Text = 'Xi';
            app.XiSpinner_NR = uispinner(app.NumiiSolveUIFigure);
            app.XiSpinner_NR.Limits = [1 Inf];
            app.XiSpinner_NR.RoundFractionalValues = 'on';
            app.XiSpinner_NR.ValueDisplayFormat = '%11g';
            app.XiSpinner_NR.ValueChangedFcn = createCallbackFcn(app, @XiSpinner_NRValueChanged, true);
            app.XiSpinner_NR.Value = 1;
            app.yEditFieldLabel = uilabel(app.NumiiSolveUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.xEditField_2Label = uilabel(app.NumiiSolveUIFigure);
            app.xEditField_2Label.Text = 'x';
            app.xEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.hEditFieldLabel = uilabel(app.NumiiSolveUIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.SegmentsLabel = uilabel(app.NumiiSolveUIFigure);
            app.SegmentsLabel.Text = 'Segments';
            app.SegmentsEditField = uieditfield(app.NumiiSolveUIFigure, 'numeric');
            app.SegmentsEditField.Limits = [1 Inf];
            app.SegmentsEditField.Value = 1;
            app.UITableEa = uitable(app.NumiiSolveUIFigure);
            app.UITableEa.ColumnName = '';
            app.UITableEa.RowName = {};
            app.XiLabel = uilabel(app.NumiiSolveUIFigure);
            app.XiLabel.Text = 'Xi';
            app.EALabel = uilabel(app.NumiiSolveUIFigure);
            app.EALabel.Text = 'Ea';
            app.xLabel = uilabel(app.NumiiSolveUIFigure);
            app.xLabel.Text = 'Ecuation: x = ';
            app.IntegralLabel = uilabel(app.NumiiSolveUIFigure);
            app.IntegralLabel.Text = 'Integral:';
            app.RIntegralLabel = uilabel(app.NumiiSolveUIFigure);
            app.RIntegralLabel.Text = '';
            app.EtLabel = uilabel(app.NumiiSolveUIFigure);
            app.EtLabel.Text = 'Et:';
            app.Label_et = uilabel(app.NumiiSolveUIFigure);
            app.Label_et.Text = '';
            app.ETLabel = uilabel(app.NumiiSolveUIFigure);
            app.ETLabel.Text = 'Et';
            app.PointsLabel = uilabel(app.NumiiSolveUIFigure);
            app.PointsLabel.Text = 'Points';
        end
    end

    methods (Access = public)

        % Construct app
        function app = methodSelector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NumiiSolveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NumiiSolveUIFigure)
        end
    end
end